classdef SphereSoundSpeeds_SV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StartDetectionsButton          matlab.ui.control.StateButton
        SvfSettingsPanel               matlab.ui.container.Panel
        LayerTypeDropDown              matlab.ui.control.DropDown
        LayerTypeDropDownLabel         matlab.ui.control.Label
        SvBinOverlapEditField          matlab.ui.control.NumericEditField
        SvBinOverlapEditFieldLabel     matlab.ui.control.Label
        BottomMarginmEditField         matlab.ui.control.NumericEditField
        BottomMarginmEditFieldLabel    matlab.ui.control.Label
        SvThresholdEditField           matlab.ui.control.NumericEditField
        SvThresholdEditFieldLabel      matlab.ui.control.Label
        StoprangeEditField             matlab.ui.control.NumericEditField
        StoprangeEditFieldLabel        matlab.ui.control.Label
        StartrangeEditField            matlab.ui.control.NumericEditField
        StartrangeEditFieldLabel       matlab.ui.control.Label
        TransceiversListBox            matlab.ui.control.ListBox
        TransceiversListBoxLabel       matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.StateButton
        EK80IPEditFieldLabel           matlab.ui.control.Label
        EK80IPEditField                matlab.ui.control.EditField
        RightPanel                     matlab.ui.container.Panel
        SoundSpeedEstimationPanel      matlab.ui.container.Panel
        msLabel                        matlab.ui.control.Label
        WaterSoundspeedEditField       matlab.ui.control.NumericEditField
        WaterSoundspeedEditFieldLabel  matlab.ui.control.Label
        WaterDensityEditFieldLabel     matlab.ui.control.Label
        WaterDensityEditField          matlab.ui.control.NumericEditField
        kgm3Label_2                    matlab.ui.control.Label
        mmLabel                        matlab.ui.control.Label
        SphereDiameterEditField        matlab.ui.control.NumericEditField
        SphereDiameterEditFieldLabel   matlab.ui.control.Label
        TransversalEditField           matlab.ui.control.NumericEditField
        TransversalEditFieldLabel      matlab.ui.control.Label
        LongitudinalEditField          matlab.ui.control.NumericEditField
        LongitudinalEditFieldLabel     matlab.ui.control.Label
        EstimateButton                 matlab.ui.control.Button
        kgm3Label                      matlab.ui.control.Label
        SphereDensityEditField         matlab.ui.control.NumericEditField
        SphereDensityEditFieldLabel    matlab.ui.control.Label
        SphereMaterialDropDown         matlab.ui.control.DropDown
        SphereMaterialDropDownLabel    matlab.ui.control.Label
        NullDetectionPanel             matlab.ui.container.Panel
        dBLabel_2                      matlab.ui.control.Label
        kHzLabel                       matlab.ui.control.Label
        dBLabel                        matlab.ui.control.Label
        MinNullHeightEditField         matlab.ui.control.NumericEditField
        MinNullHeightEditFieldLabel    matlab.ui.control.Label
        MinNullDistanceEditField       matlab.ui.control.NumericEditField
        MinNullDistanceEditFieldLabel  matlab.ui.control.Label
        MinNullProminenceEditField     matlab.ui.control.NumericEditField
        MinNullProminenceEditFieldLabel  matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        aliveTimer
        avgCount
        currTime        % Holds time of the current ping to determine if datagram changes
        EK80RequestID   % Description
        EK80 % Description
        EK80CLIENTID % Description
        EK80CLIENTSEQNO % Description
        EK80transceiverIDs
        frequency
        hData
        hLegend
        hTheoreticalTS
        hXlinesMeasured
        hXlinesTheory
        hYline
        SubID
        Sv
        Sv_vs_F
        UDPObj
    end
    
    methods (Access = private)
        
        function connect2EK80(app)

            try

                %% Get/set EK80 connection settings
                app.EK80RequestID = 1;  % Init. EK80 request ID

                %% Request server info and open connection

                % Create UDP port object
                app.EK80 = udpport("datagram", "IPV4");

                % Send request server info
                write(app.EK80, ['RSI' char(0)], "char", ...
                    app.EK80IPEditField.Value, 37655);

                % Read header. If nothing is sent back, then a connection setting must
                % be wrong.
                try
                    % Read datagram response
                    data = read(app.EK80, 1);

                    % Parse header
                    header = char(data.Data(1:4));

                    if ~strcmp(header, ['SI2' char(0)])
                        error('Incorrect response.  Check IP address')
                    end
                catch ME
                    error('No response from EK80.  Check settings')
                end

                % Get remote commandport which should be used to set up subscriptions and
                commandPort = typecast(uint8(data.Data(265:268)), 'int32');  % Command Port

                %% Connect to EK80 server

                % Try to connect with a user and password which must be defined in
                % server EK80 application (Users and Passwords dialogue).
                write(app.EK80, ['CON' char(0) 'Name:Simrad;Password:' char(0)], 'char', ...
                    app.EK80IPEditField.Value, commandPort);

                % Read datagram response
                data = read(app.EK80, 1);

                % Parse header
                header = char(data.Data(1:4));

                % Receive response
                if strcmp(header, ['RES' char(0)])
                    response = char(data.Data(31:end));
                else
                    error('Unknown response: %s', header);
                end

                % Get CLIENTID
                tokens = regexp(response, 'ClientID:(\d+),', 'tokens');
                app.EK80CLIENTID = tokens{1}{1};

                % Initialiaze client sequence number
                app.EK80CLIENTSEQNO = 1;

            catch ME

                % Delete udp object to EK80 server
                if isfield(app, 'EK80')
                    if isvalid(app.EK80); delete(app.EK80); end
                end

                error(ME.message);
            end
        end
        
        function response = sendrequest(app, str)

            % Create request string
            header = ['REQ' char(0)];                           % Header
            temp = [int2str(app.EK80CLIENTSEQNO) ',1,1'];   % Sequence number
            msgcontrol = [temp repmat(char(0), 1, 22-length(temp))];    % Msg control
            s = [header msgcontrol str];                        % Put it all together

            % Send command
            flushinput(app.EK80)
            write(app.EK80, s, 'char');                     % Send to EK80

            % Read datagrams until we receive the response
            header = '';
            while ~strcmp(header, ['RES' char(0)])

                % Received request response
                data = read(app.EK80, 1);
                
                % Parse header
                header = char(data.Data(1:4));
            end

            response = char(data.Data(28:end));

            app.EK80CLIENTSEQNO = app.EK80CLIENTSEQNO+1;
        end
        
        function str = readbetween(~, FirstPattern, LastPattern, Text)
        % Extracts the string located between FirstPattern and LastPattern in the
        % string Text

            pat = [FirstPattern '(.*)' LastPattern];
            temp = regexp(Text, pat, 'tokens');
            str = temp{:}{:};
        end
        
        function dataReceivedFcn(app, src, event)

            % Read the datagram packet
            dg = read(src, 1);
            rawBytes = uint8(dg.Data);
            
            % Read header
            header = char(rawBytes(1:4));
        
            % Read rest of header information
            typecast(rawBytes(5:8), 'int32');       % Sequence number
            subID = typecast(rawBytes(9:12), 'int32');      % Subscription ID
            currMsg = typecast(rawBytes(13:14), 'uint16');  % Current message number
            totalMsg = typecast(rawBytes(15:16), 'uint16');      	% Total number of UDP messages
            numbytes = typecast(rawBytes(17:18), 'uint16');    	% Number of bytes in data field

            % If first message, get time
            if isequal(currMsg, 1)
                time = double(typecast(rawBytes(19:26), 'int64')) * ...
                    100/1e9/60/60/24 + datetime(1601,1,1);
            end
                               
            % Get index for subscription ID
            % subIdx = subID == app.SubID;
            subIdx = app.TransceiversListBox.ValueIndex;

            % If it's not the first message but that subscription is empty,
            % read and discard that datagram.
            if ~isequal(currMsg, 1) && isempty(app.Sv{subIdx})
            
                disp('Not first message and no prior data. Discarding...');

            % Otherwise, the data may be useful
            else
        
                % If first message in datagram, get time and number of
                % traces
                if isequal(currMsg, 1)

                    % Calculate the ping time
                    time = double(typecast(rawBytes(19:26), 'int64')) * ...
                        100/1e9/60/60/24 + datetime(1601,1,1);
        
                    data = typecast(rawBytes(27:numbytes+18), 'int8');
                            
                    % If timestamp has changed but subscription data exists,
                    % then discard current existing contents and treat as a new
                    % ping.
                    disp(time)
                    disp(app.currTime)
                    disp(app.Sv)
                    disp(currMsg)
                    disp(totalMsg)
                    % disp(~isequal(time, app.currTime) && ...
                    %         ~isempty(app.Sv{subIdx}))
                    if ~isequal(time, app.currTime) && ~isempty(app.Sv{subIdx})
        
                        % Reinitialize subscription data (discard old contents)
                        app.Sv{subIdx} = [];
                        
                        disp('Timestamp differs...dumping old data.');
                    else
                        disp('Can you see this?')
                    end
        
                    app.currTime = time;    % Store current timestamp
                            
                % Otherwise, it's data that needs to be appended to existing
                % data
                else
        
                    % Read datagram data
                    data = typecast(rawBytes(19:numbytes+18), 'int8');
                end
        
                % If data exists, store in appropriate cell array
                if exist('data', 'var')
                    app.Sv{subIdx} = [app.Sv{subIdx}; data'];
                end
            end
    
            % If it's not the last datagram, exit
            if currMsg ~= totalMsg || ~exist('data', 'var')
                fprintf('Message %d out of %d. Returning\n', currMsg, totalMsg)
                return
            end

            fprintf('Message %d out of %d. Saving and plotting data!\n', currMsg, totalMsg)
        
            % Convert data from double to int8
            data = int8(app.Sv{subIdx});
                
            % Parse out the data
            dSa = typecast(data(1:8), 'int64');
            dDistance = typecast(data(9:16), 'double');
            freqLims = typecast(data(17:24), 'single');
            SVFrequencies = typecast(data(25:4024), 'single');
        
            % If data exists, update stored variable with new average
            if any(SVFrequencies)

                % Set index based on transceiver selection box. When
                % switching to TS(f) this should be deleted so that it
                % doesn't overwrite subIdx
                subIdx = app.TransceiversListBox.ValueIndex;

                % Create frequency variable if it doesn't already exist
                if isempty(app.frequency{subIdx})
                    app.frequency{subIdx} = linspace(freqLims(1), freqLims(2), ...
                        length(SVFrequencies))';
                end

                % If first detection for that frequency, then initialize
                if app.avgCount(subIdx) == 0
                    app.Sv_vs_F{subIdx} = SVFrequencies;

                % Else, update with average
                else
                    app.Sv_vs_F{subIdx} = app.Sv_vs_F{subIdx} + ...
                        ((SVFrequencies-app.Sv_vs_F{subIdx})./app.avgCount(subIdx));
                end

                % Increment averaging counter
                app.avgCount(subIdx) = app.avgCount(subIdx)+1;
            end
                
            % Plot measured TS
            x = [];
            y = [];
            for i = 1:length(app.Sv_vs_F)
                x = [x; app.frequency{i}];
                y = [y; app.Sv_vs_F{i}];
                % set(app.hData, 'XData', app.frequency{subIdx}/1e3, ...
                %     'YData', app.Sv_vs_F{subIdx})
            end
            [x, I] = sort(x);
            y = y(I);

            % 1. Find unique x values and replace with averaged y values
            [x, ~, ic] = unique(x);
            y = accumarray(ic, y, [], @mean);
            
            % Remove all data tips from the axes to prevent the listener error
            delete(findall(app.UIAxes, 'Type', 'DataTip'));

            set(app.hData, 'XData', x./1e3, 'YData', y)

            drawnow limitrate;

            % Plot theoretical TS
            TS_Theory = sphereTS(app, ...
                app.LongitudinalEditField.Value, ...
                app.TransversalEditField.Value, ...
                app.SphereDiameterEditField.Value, ...
                app.SphereDensityEditField.Value, ...
                app.WaterDensityEditField.Value, ...
                x', ...
                app.WaterSoundspeedEditField.Value);
            set(app.hTheoreticalTS, ...
                'XData', x/1e3, ...
                'YData', TS_Theory)

            % Turn legend visibility on
            set(app.hLegend, 'Visible', 'on')

            % % Perform peak detection on measured data
            % [~,LOCS_measured] = findpeaks(-y, x/1e3, ...
            %     'MinPeakProminence', app.MinNullProminenceEditField.Value, ...
            %     'MinPeakDistance', app.MinNullDistanceEditField.Value, ...
            %     'MinPeakHeight', -app.MinNullHeightEditField.Value);
            % 
            % % Delete any old xlines
            % if ~isempty(app.hXlinesMeasured)
            %     delete(app.hXlinesMeasured); 
            % end
            % 
            % % Update peak detection lines
            % if ~isempty(LOCS_measured)
            %     app.hXlinesMeasured = xline(app.UIAxes, LOCS_measured, ...
            %         'Color',get(app.hData,'Color'), ...
            %         'LineWidth', 1, 'LineStyle', '--');
            % end
            % 
            % % Only retain locations close to the measured
            % % locations
            % radius = 10; % Radius, in kHz
            % found_locs = [];
            % f = x/1e3;
            % 
            % % Cycle through each of the measured locations and search
            % % nearby them
            % for i = 1:length(LOCS_measured)
            % 
            %     % Define the local window for this specific target
            %     idx = f > LOCS_measured(i)-radius & f <= LOCS_measured(i)+radius;
            % 
            %     % Perform peak detection on theoretical data
            %     [p,LOCS_theory] = findpeaks(-TS_Theory(idx), ...
            %         f(idx), ...
            %         'MinPeakProminence', app.MinNullProminenceEditField.Value, ...
            %         'MinPeakDistance', app.MinNullDistanceEditField.Value, ...
            %         'MinPeakHeight', -app.MinNullHeightEditField.Value);
            % 
            %     if ~isempty(LOCS_theory)
            %         % If multiple peaks exist in the ROI, pick the one closest to the center
            %         % or simply the highest one:
            %         [~, max_idx] = max(p);
            % 
            %         found_locs(end+1) = LOCS_theory(max_idx);
            %     end
            % 
            % end
            % 
            % % Delete any old xlines
            % if ~isempty(app.hXlinesTheory)
            %     delete(app.hXlinesTheory); 
            % end
            % 
            % % Update peak detection lines
            % if ~isempty(found_locs)
            %     app.hXlinesTheory = xline(app.UIAxes, found_locs, ...
            %         'Color',get(app.hTheoreticalTS,'Color'), ...
            %         'LineWidth', 1, 'LineStyle', '--');
            % end

            app.StatusTextArea.Value = string(app.currTime);
    
            % Re-initialize the data subscription variables
            app.currTime = NaN;
            app.Sv{subIdx} = [];
        end
        
        function TS = sphereTS(app, c1, c2, d, rho1, rho, f, c)
            % Function used to estimate the TS of a sphere for given sound
            % speeds, density, diameter, water sound speed, and frequency.
            % Inputs are:
            %   c1 = longitudinal sound speed (m/s)
            %   c2 = tranversal sound speed (m/s)
            %   d = sphere diameter (mm)
            %   rho1 = sphere density (kg/m^3)
            %   rho2 = water density (kg/m^3)
            %   f = frequency (Hz)
            %   c = water sound speed (m/s)

            a = 1e-3*d/2;               % Calculate sphere radius

            % Cycle through sound speeds
            TS = nan(length(c), length(f));         % Initialize TS variable
            for i = 1:length(c)

                % Calculate variables that don't change with l
                k = 2*pi*f/c(i);                            % Wavenumber
                q = k*a;                                    % Circumference/wavelength
                q1 = q * c(i) / c1;                         % Relative long. c
                q2 = q * c(i) / c2;                         % Relative trans. c
                alpha = 2 .* rho1./rho .* (c2./c(i)).^2;    % Used in beta and B2 calc
                beta = rho1./rho .* (c1./c(i)).^2 - alpha;  % used in B2 calc

                % Cycle through l's
                buff = zeros(1,length(q));          % Create summation variable
                flag = 0;                           % Flag for exiting while loop
                l = 0;                              % Initialize l variable

                % Perform summation in equation 7 until the results for every frequency
                % result in NaN.
                while flag == 0

                    % Compute variables in equations 6a to 6h
                    A1 = 2.*l.*(l+1) .* (q1 .* j1(app,l,q1) - j(app,l,q1));
                    A2 = (l.^2 + l - 2) .* j(app,l,q2) + q2.^2 .* j2(app,l,q2);
                    B1 = q .* (A2.*q1.*j1(app,l,q1) - A1.*j(app,l,q2));
                    B2 = A2.*q1.^2 .* (beta.*j(app,l,q1) - alpha.*j2(app,l,q1)) - ...
                        A1.*alpha .* (j(app,l,q2) - q2.*j1(app,l,q2));
                    nu = atan(-(B2.*j1(app,l,q) - B1.*j(app,l,q)) ./ ...
                        (B2.*y1(app,l,q) - B1.*y(app,l,q)));

                    % Find frequency indices which have a nu value that is not a NaN
                    idx = ~isnan(nu);

                    % Calculate the changes to each element in the buffer array
                    change = (-1).^l .* (2.*l+1) .* sin(nu(idx)) .* exp(1i.*nu(idx));

                    % If the max change is less than eps, break out of loop
                    if max(abs(change)) < eps
                        break
                    end

                    % Perform summation from equation 7.  This will only perform the
                    % summation for frequencies which continue to have nu values that
                    % are real, and not a NaN.
                    buff(idx) = buff(idx) + (-1).^l .* (2.*l+1) .* sin(nu(idx)) .* ...
                        exp(1i.*nu(idx));

                    l = l + 1;                      % Increment l by 1

                    % Clear variables defined in next loop iteration
                    clear idx nu P B2 B1 A2 A1
                end

                f_inf = -2./q .* buff;                  % Calculate Equation 7
                sigma = pi .* a.^2 .* abs(f_inf).^2;    % Calculate Equation 8
                TS(i,:) = 10*log10(sigma./(4*pi));      % Calculate TS (Equation 9)

                % Clear variables defined in next sound speed loop iteration
                clear sigma f_inf l flag buff q2 q1 q k
            end
        end
        
        % Plot progress of estimation process
        function stop = plotProgress(app, x, optimValues, state)

            stop = false;

            f = get(app.hData, 'XData').*1e3;
            SV = get(app.hData, 'YData');
        
            % Calculate TS using current sound speed values
            if strcmp(state, 'iter')
                TS_Theory = sphereTS(app, x(1), x(2), ...
                    app.SphereDiameterEditField.Value, ...
                    app.SphereDensityEditField.Value, ...
                    app.WaterDensityEditField.Value, ...
                    f, ...
                    app.WaterSoundspeedEditField.Value);

                % Update plot of theoreitcal TS
                set(app.hTheoreticalTS, ...
                    'XData', f/1e3, ...
                    'YData', TS_Theory)

                % % Find nulls in measured data
                % [~,LOCS_measured] = findpeaks(-SV, f, ...
                %     'MinPeakProminence', 10, 'MinPeakDistance', 5e3);
                %     % 'MinPeakProminence', app.MinNullProminenceEditField.Value, ...
                %     % 'MinPeakDistance', app.MinNullDistanceEditField.Value, ...
                %     % 'MinPeakHeight', -app.MinNullHeightEditField.Value);
                % 
                % Delete any old xlines for measured data
                if ~isempty(app.hXlinesMeasured)
                    delete(app.hXlinesMeasured);
                end
                % 
                % % Update peak detection lines
                % if ~isempty(LOCS_measured)
                %     app.hXlinesMeasured = xline(app.UIAxes, LOCS_measured/1e3, ...
                %         'Color',get(app.hData,'Color'), ...
                %         'LineWidth', 1, 'LineStyle', '--');
                % end
                % 
                % % Find nulls in theoretical data
                % [~,LOCS_theory] = findpeaks(-TS_Theory, f, ...
                %     'MinPeakProminence', 10, 'MinPeakDistance', 5e3);
                % 
                % Delete any old xlines
                if ~isempty(app.hXlinesTheory)
                    delete(app.hXlinesTheory);
                end
                % 
                % % Update peak detection lines
                % if ~isempty(LOCS_theory)
                %     app.hXlinesTheory = xline(app.UIAxes, LOCS_theory/1e3, ...
                %         'Color',get(app.hTheoreticalTS,'Color'), ...
                %         'LineWidth', 1, 'LineStyle', '--');
                % end
                % 
                % Update plot and GUI with results
                app.LongitudinalEditField.Value = x(1);
                app.TransversalEditField.Value = x(2);

                drawnow 
            end
        end
        
        % Function called by timer object to keep connection alive
        function keepAlive(app)

            % Get transceiver IDs just to keep connection alive
            rString = ['<request><clientInfo><cid>' app.EK80CLIENTID '</cid><rid>' int2str(app.EK80RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/Channels</paramName><time>0</time></GetParameter></method></request>' char(0)];
            sendrequest(app, rString);
            
        end
        
        % Spherical bessel function of the first order
        function answer = j(app,n,x)
            answer = besselj(n+.5, x) .* sqrt(pi./(2*x));
        end

        % First derivative of spherical bessel function of the first order
        function answer = j1(app,n,x)
            answer = (j(app,n-1,x) - j(app,n+1,x))./2 - j(app,n,x)./(2.*x);
        end

        % Second derivative of spherical bessel function of the first order
        function answer = j2(app,n,x)
            answer = (x.^2.*j(app,n-2,x) - 2.*x.^2.*j(app,n,x) + x.^2.*j(app,n+2,x) - ...
                2.*x.*j(app,n-1,x) + 2.*x.*j(app,n+1,x) + 3.*j(app,n,x)) ./ (4.*x.^2);
        end

        % Spherical bessel function of the second order
        function answer = y(app,n,x)
            answer = bessely(n+.5, x) .* sqrt(pi./(2*x));
        end

        % First derivative of spherical bessel function of the second order
        function answer = y1(app,n,x)
            answer = (y(app,n-1,x) - y(app,n+1,x))./2 - y(app,n,x)./(2.*x);
        end
        
        function score = minFun(app, x)

            try

                % Get latest data from plot
                f = get(app.hData, 'XData').*1e3;
                SV = get(app.hData, 'YData');

                % Calculate theoretical TS using latest values of sphere sound speeds
                c1 = x(1);
                c2 = x(2);
                TS_Theory = sphereTS(app, c1, c2, ...
                    app.SphereDiameterEditField.Value, ...
                    app.SphereDensityEditField.Value, ...
                    app.WaterDensityEditField.Value, ...
                    f, ...
                    app.WaterSoundspeedEditField.Value);

                score = sqrt(mean((SV - TS_Theory).^2));


                % % Find nulls
                % [~,LOCS] = findpeaks(-SV, f, ...
                %     'MinPeakProminence', 10, 'MinPeakDistance', 5e3);
                % [~,LOCS2] = findpeaks(-TS_Theory, f, ...
                %     'MinPeakProminence', 10, 'MinPeakDistance', 5e3);
                % 
                % % Compute error
                % score = sqrt(sum((LOCS2-LOCS).^2));

                % % Find nulls in measured data
                % [~,LOCS_measured] = findpeaks(-SV, f/1e3, ...
                %     'MinPeakProminence', app.MinNullProminenceEditField.Value, ...
                %     'MinPeakDistance', app.MinNullDistanceEditField.Value, ...
                %     'MinPeakHeight', -app.MinNullHeightEditField.Value);
                % 
                % % Only retain locations close to the measured locations
                % radius = 10; % Radius, in kHz
                % found_locs = [];
                % 
                % % Cycle through each of the measured locations and search
                % % nearby them for the theoretical data
                % for i = 1:length(LOCS_measured)
                % 
                %     % Define the local window for this specific target
                %     idx = f/1e3 > LOCS_measured(i)-radius & f/1e3 <= LOCS_measured(i)+radius;
                % 
                %     % Perform peak detection on theoretical data
                %     [p,LOCS_theory] = findpeaks(-TS_Theory(idx), ...
                %         f(idx)/1e3, ...
                %         'MinPeakProminence', app.MinNullProminenceEditField.Value, ...
                %         'MinPeakDistance', app.MinNullDistanceEditField.Value, ...
                %         'MinPeakHeight', -app.MinNullHeightEditField.Value);
                % 
                %     if ~isempty(LOCS_theory)
                %         % If multiple peaks exist in the ROI, pick the one closest to the center
                %         % or simply the highest one:
                %         [~, max_idx] = max(p);
                % 
                %         found_locs(end+1) = LOCS_theory(max_idx);
                %     end
                % end          
                % 
                % % Compute error
                % score = sqrt(sum((LOCS_theory-LOCS_measured).^2));

            catch ME
                % disp('Mismatch in # of nulls!')
                score = 999999;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Create UDP object for reaching single target detection data
            app.UDPObj = udpport("datagram");
            % app.UDPObj = udpport("byte", "IPV4");

            % Link the callback using an anonymous function
            configureCallback(app.UDPObj, "datagram", 1, @(src, event) dataReceivedFcn(app, src, event));
            % configureCallback(app.UDPObj, "byte", 1, @(src, event) dataReceivedFcnBytes(app, src, event));

            % Turn holding on for the figure
            hold(app.UIAxes, 'on')

            % Initialize plot for measured TS
            app.hData = plot(app.UIAxes,NaN,NaN,'LineWidth',1.5);

            % Initialize plot for theoretical TS
            app.hTheoreticalTS = plot(app.UIAxes,NaN,NaN,'LineWidth',1.5);

            app.hLegend = legend(app.UIAxes,{'Measured','Theoretical'}, ...
                'Location','southeast','Visible','off','AutoUpdate','off');

            % % Initialize plot for interactive yline
            % app.hYline = drawline(app.UIAxes, ...
            %     'Position', [0 -42; 500 -42], ...
            %     'Color', 'k','LineWidth',1);

            % Initialize xlines for measured data null frequencies
            app.hXlinesMeasured = xline(app.UIAxes,NaN, ...
                'Color',get(app.hData,'Color'), ...
                'LineWidth',1);

            app.hXlinesTheory = xline(app.UIAxes,NaN, ...
                'Color',get(app.hTheoreticalTS,'Color'), ...
                'LineWidth',1);

            app.aliveTimer = timer("ExecutionMode","fixedRate", ...
                "Period",3, ...
                "BusyMode","queue", ...
                "TimerFcn",@(~,~) app.keepAlive());


        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
           
            value = app.ConnectButton.Value;
            
            % If START pressed, connect to EK80 and start timer
            if value
    
                % Try to connect to EK80
                try

                    set(app.ConnectButton, 'Text', 'STOP', 'BackgroundColor', 'r')
                    % app.StatusTextArea.Value = 'Trying to connect to EK80 server...';

                    % Call function to connect to EK80 server
                    connect2EK80(app);
                    
                    % If connection successful, update GUI
                    app.StatusTextArea.Value = 'Connected to EK80!';
                    
                    % Start timer function to keep connection alive
                    start(app.aliveTimer)

                    % Get transceiver IDs
                    rString = ['<request><clientInfo><cid>' app.EK80CLIENTID '</cid><rid>' int2str(app.EK80RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/Channels</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    response = sendrequest(app, rString);
                    transceiverID = readbetween(app, '<value dt="8200">','</value>',response);
                    app.EK80transceiverIDs = regexp(transceiverID, '[^,]*', 'match');    % transceiver IDs for each frequency

                    % Get pulse modes (CW or FM) for each transceiver
                    pulseForm = nan(size(app.EK80transceiverIDs));
                    for i = 1:length(app.EK80transceiverIDs)
                        rString = ['<request><clientInfo><cid>' app.EK80CLIENTID '</cid><rid>' int2str(app.EK80RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' app.EK80transceiverIDs{i} '/PulseForm</paramName><time>0</time></GetParameter></method></request>' char(0)];
                        response = sendrequest(app, rString);
                        pulseForm(i) = str2double(readbetween(app, '<value dt="3">','</value>',response));
                    end

                    % Remove transceivers that aren't in FM mode
                    app.EK80transceiverIDs(pulseForm ~= 1) = [];
                    app.TransceiversListBox.Items = app.EK80transceiverIDs;

                    % Initialize TS cell arrays to hold data for each frequency
                    app.Sv = cell(length(app.EK80transceiverIDs), 1);
                    app.Sv_vs_F = cell(length(app.EK80transceiverIDs), 1);
                    app.avgCount = zeros(length(app.EK80transceiverIDs), 1);
                    app.frequency = cell(length(app.EK80transceiverIDs), 1);

                % If connection was unsuccessul
                catch ME

                    % Display message as to why it failed
                    app.StatusTextArea.Value = 'Failed to connect to EK80!';
                    disp(ME.message)
                    
                    % Change button to display Start
                    set(app.ConnectButton, 'Value', 0, 'Text', 'START', 'BackgroundColor', 'g')

                    % % Exit callback function
                    % return
                end

            % Otherwise stop timer and disconnect from EK80
            else

                % Stop alive timer
                stop(app.aliveTimer)

                % Change button to display Start
                set(app.ConnectButton, 'Text', 'START', 'BackgroundColor', 'g')
                app.StatusTextArea.Value = 'Disconnected from EK80!';

                % Disconnect from EK80
                fwrite(app.EK80, ['DIS' char(0) ...
                    'Name:Simrad;Password:' char(0)], 'char');

                % Clear EK80 udp obect
                delete(app.EK80)
            end
        end

        % Value changed function: StartDetectionsButton
        function StartDetectionsButtonValueChanged(app, event)
            value = app.StartDetectionsButton.Value;
            
            % Get list of selected transceivers. When switching to TS(f)
            % this should be updated to allow for multiple selections, in
            % which case this should not be encapsulated in a cell, as it
            % will already be output in a cell array
            transceivers = {app.TransceiversListBox.Value};

            % If enabled, create a data subscription for the currently
            % selected transceiver(s) from the transceiver list
            if value

                % Create a UDP object for receiving subscription data
                % app.UDPObj = udpport("datagram", "IPV4");
                % app.udp.DatagramReceivedFcn = {@readUDP};

                % Reinitialize Subscription IDs. When using Sv(f), this
                % will always only be a single value. When switching to
                % TS(f) this can be multiple values used to determine which
                % datagram belongs to which subscription
                app.SubID = nan(length(transceivers),1);
                
                % Create a subscription for each transceiver
                for i = 1:length(transceivers)

                     % Request string for Sv(f) data
                    rString = ['<request><clientInfo><cid>' ...
                        app.EK80CLIENTID ...
                        '</cid>' ...
                        '<rid>' int2str(app.EK80RequestID) '</rid>' ...
                        '</clientInfo><type>invokeMethod</type>' ...
                        '<targetComponent>RemoteDataServer</targetComponent><method>' ...
                        '<Subscribe><requestedPort>' ...
                        int2str(app.UDPObj.LocalPort) ...
                        '</requestedPort><dataRequest>' ...
                        'IntegrationChirp' ...
                        ',ChannelID=' transceivers{i} ...
                        ',State=start' ...
                        ',LayerType=' app.LayerTypeDropDown.Value ...
                        ',Update=UpdatePing' ...
                        ',Range=' num2str(app.StoprangeEditField.Value - app.StartrangeEditField.Value) ...
                        ',RangeStart=' num2str(app.StartrangeEditField.Value) ...
                        ',Margin=' num2str(app.BottomMarginmEditField.Value) ...
                        ',SvThreshold=' num2str(app.SvThresholdEditField.Value) ...
                        ',SvBinOverlap=' num2str(app.SvBinOverlapEditField.Value) ...
                        '</dataRequest></Subscribe></method></request>' char(0)];
                               
                    try
                        response = sendrequest(app, rString);
                    catch ME
                        error('Error during subscription.  Stoppin program');
                    end

                    % Store subscription ID
                    app.SubID(i) = str2double(readbetween(app, '<subscriptionID dt="3">', '</subscriptionID>',response));
                end

                % % Initialize TS cell arrays to hold data for each frequency
                % app.Sv = cell(length(transceivers), 1);
                % app.Sv_vs_F = cell(length(transceivers), 1);
                % app.avgCount = zeros(length(transceivers), 1);
                % app.frequency = cell(length(transceivers), 1);

                % Configure callback for when data is received
                flush(app.UDPObj);
                configureCallback(app.UDPObj, "datagram", 1, @(src,event) dataReceivedFcn(app, src, event))
                % configureCallback(app.UDPObj, "byte", 1, @(src,event) dataReceivedFcnBytes(app, src, event))

            % Otherwise if stop subscription button was pressed,
            % unsubscribe
            else

                fprintf('Stopping detections...')
                    
                % Stop Sv(f) subscription
                for i = 1:length(transceivers)
                    rString = ['<request><clientInfo><cid>' app.EK80CLIENTID '</cid><rid>' int2str(app.EK80RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>RemoteDataServer</targetComponent><method><Unsubscribe><subscriptionID>' num2str(app.SubID(i)) '</subscriptionID></Unsubscribe></method></request>' char(0)];
                    response = sendrequest(app, rString);
                end

                fprintf('Done!\n')
                configureCallback(app.UDPObj, "off");

                % % Close UDP connection
                % delete(app.UDPObj)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Clear the UDP callback first to stop processing
            configureCallback(app.UDPObj, "off");
    
            % Delete the UDP object to free up the port
            delete(app.UDPObj);

            % Delete the alive timer
            delete(app.aliveTimer)

            % Delete the app
            delete(app)
        end

        % Button pushed function: EstimateButton
        function EstimateButtonPushed(app, event)
            
            % Set options for minimization
            options = optimset('fminsearch');
            options.Display = 'iter';
            options.OutputFcn = @(x,o,s) app.plotProgress(x,o,s);

            % Define starting points based on sphere type
            switch app.SphereMaterialDropDown.Value
                case 'WC'
                    X0 = [6853 4171];
                    %             X0 = [7000 3900];
                case 'Cu'
                    X0 = [4760 2289];
            end

            % Conduct minimization. This function minimizes the RMS of the entire
            % TS spectra
            X = fminsearch(@(x) app.minFun(x), X0, options);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {610, 610};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EK80IPEditField = uieditfield(app.LeftPanel, 'text');
            app.EK80IPEditField.Value = '161.55.232.152';
            app.EK80IPEditFieldLabel = uilabel(app.LeftPanel);
            app.EK80IPEditFieldLabel.Text = 'EK80 IP';
            app.ConnectButton = uibutton(app.LeftPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.TransceiversListBoxLabel = uilabel(app.LeftPanel);
            app.TransceiversListBoxLabel.Text = 'Transceivers';
            app.TransceiversListBox = uilistbox(app.LeftPanel);
            app.TransceiversListBox.Items = {};
            app.TransceiversListBox.Value = {};
            app.SvfSettingsPanel = uipanel(app.LeftPanel);
            app.SvfSettingsPanel.Title = 'Sv(f) Settings';
            app.StartrangeEditFieldLabel = uilabel(app.SvfSettingsPanel);
            app.StartrangeEditFieldLabel.Text = 'Start range';
            app.StartrangeEditField = uieditfield(app.SvfSettingsPanel, 'numeric');
            app.StartrangeEditField.Value = 3;
            app.StoprangeEditFieldLabel = uilabel(app.SvfSettingsPanel);
            app.StoprangeEditFieldLabel.Text = 'Stop range';
            app.StoprangeEditField = uieditfield(app.SvfSettingsPanel, 'numeric');
            app.StoprangeEditField.Value = 8;
            app.SvThresholdEditFieldLabel = uilabel(app.SvfSettingsPanel);
            app.SvThresholdEditFieldLabel.Text = 'Sv Threshold';
            app.SvThresholdEditField = uieditfield(app.SvfSettingsPanel, 'numeric');
            app.SvThresholdEditField.Value = -70;
            app.BottomMarginmEditFieldLabel = uilabel(app.SvfSettingsPanel);
            app.BottomMarginmEditFieldLabel.Text = 'Bottom Margin (m)';
            app.BottomMarginmEditField = uieditfield(app.SvfSettingsPanel, 'numeric');
            app.BottomMarginmEditField.Value = 0.5;
            app.SvBinOverlapEditFieldLabel = uilabel(app.SvfSettingsPanel);
            app.SvBinOverlapEditFieldLabel.Text = 'Sv Bin Overlap (%)';
            app.SvBinOverlapEditField = uieditfield(app.SvfSettingsPanel, 'numeric');
            app.SvBinOverlapEditField.Value = 50;
            app.LayerTypeDropDownLabel = uilabel(app.SvfSettingsPanel);
            app.LayerTypeDropDownLabel.Text = 'Layer Type';
            app.LayerTypeDropDown = uidropdown(app.SvfSettingsPanel);
            app.LayerTypeDropDown.Items = {'Surface', 'Pelagic', 'Bottom'};
            app.LayerTypeDropDown.Value = 'Surface';
            app.StartDetectionsButton = uibutton(app.LeftPanel, 'state');
            app.StartDetectionsButton.ValueChangedFcn = createCallbackFcn(app, @StartDetectionsButtonValueChanged, true);
            app.StartDetectionsButton.Text = 'Start Detections';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Sv(f)')
            xlabel(app.UIAxes, 'Frequency (kHz)')
            ylabel(app.UIAxes, 'Sv (dB)')
            zlabel(app.UIAxes, 'Z')
            app.StatusTextAreaLabel = uilabel(app.RightPanel);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.RightPanel);
            app.NullDetectionPanel = uipanel(app.RightPanel);
            app.NullDetectionPanel.Title = 'Null Detection';
            app.MinNullProminenceEditFieldLabel = uilabel(app.NullDetectionPanel);
            app.MinNullProminenceEditFieldLabel.Text = 'Min Null Prominence';
            app.MinNullProminenceEditField = uieditfield(app.NullDetectionPanel, 'numeric');
            app.MinNullProminenceEditField.Value = 5;
            app.MinNullDistanceEditFieldLabel = uilabel(app.NullDetectionPanel);
            app.MinNullDistanceEditFieldLabel.Text = 'Min Null Distance';
            app.MinNullDistanceEditField = uieditfield(app.NullDetectionPanel, 'numeric');
            app.MinNullDistanceEditField.Value = 5;
            app.MinNullHeightEditFieldLabel = uilabel(app.NullDetectionPanel);
            app.MinNullHeightEditFieldLabel.Text = 'Min Null Height';
            app.MinNullHeightEditField = uieditfield(app.NullDetectionPanel, 'numeric');
            app.MinNullHeightEditField.Value = -40;
            app.dBLabel = uilabel(app.NullDetectionPanel);
            app.dBLabel.Text = 'dB';
            app.kHzLabel = uilabel(app.NullDetectionPanel);
            app.kHzLabel.Text = 'kHz';
            app.dBLabel_2 = uilabel(app.NullDetectionPanel);
            app.dBLabel_2.Text = 'dB';
            app.SoundSpeedEstimationPanel = uipanel(app.RightPanel);
            app.SoundSpeedEstimationPanel.Title = 'Sound Speed Estimation';
            app.SphereMaterialDropDownLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.SphereMaterialDropDownLabel.Text = 'Sphere Material';
            app.SphereMaterialDropDown = uidropdown(app.SoundSpeedEstimationPanel);
            app.SphereMaterialDropDown.Items = {'WC', 'Cu'};
            app.SphereMaterialDropDown.Value = 'WC';
            app.SphereDensityEditFieldLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.SphereDensityEditFieldLabel.Text = 'Sphere Density';
            app.SphereDensityEditField = uieditfield(app.SoundSpeedEstimationPanel, 'numeric');
            app.SphereDensityEditField.ValueDisplayFormat = '%.0f';
            app.SphereDensityEditField.Value = 14900;
            app.kgm3Label = uilabel(app.SoundSpeedEstimationPanel);
            app.kgm3Label.Text = 'kg/m^3';
            app.EstimateButton = uibutton(app.SoundSpeedEstimationPanel, 'push');
            app.EstimateButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateButtonPushed, true);
            app.EstimateButton.Text = 'Estimate';
            app.LongitudinalEditFieldLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.LongitudinalEditFieldLabel.Text = 'Longitudinal';
            app.LongitudinalEditField = uieditfield(app.SoundSpeedEstimationPanel, 'numeric');
            app.LongitudinalEditField.Editable = 'off';
            app.LongitudinalEditField.Value = 6853;
            app.TransversalEditFieldLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.TransversalEditFieldLabel.Text = 'Transversal';
            app.TransversalEditField = uieditfield(app.SoundSpeedEstimationPanel, 'numeric');
            app.TransversalEditField.Editable = 'off';
            app.TransversalEditField.Value = 4171;
            app.SphereDiameterEditFieldLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.SphereDiameterEditFieldLabel.Text = 'Sphere Diameter';
            app.SphereDiameterEditField = uieditfield(app.SoundSpeedEstimationPanel, 'numeric');
            app.SphereDiameterEditField.Value = 38.1;
            app.mmLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.mmLabel.Text = 'mm';
            app.kgm3Label_2 = uilabel(app.SoundSpeedEstimationPanel);
            app.kgm3Label_2.Text = 'kg/m^3';
            app.WaterDensityEditField = uieditfield(app.SoundSpeedEstimationPanel, 'numeric');
            app.WaterDensityEditField.Value = 1025;
            app.WaterDensityEditFieldLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.WaterDensityEditFieldLabel.Text = 'Water Density';
            app.WaterSoundspeedEditFieldLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.WaterSoundspeedEditFieldLabel.Text = 'Water Soundspeed';
            app.WaterSoundspeedEditField = uieditfield(app.SoundSpeedEstimationPanel, 'numeric');
            app.WaterSoundspeedEditField.Value = 1500;
            app.msLabel = uilabel(app.SoundSpeedEstimationPanel);
            app.msLabel.Text = 'm/s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SphereSoundSpeeds_SV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end